classdef DecorrApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ResetAppMenu                   matlab.ui.container.Menu
        ImportImagingSettingsMenu      matlab.ui.container.Menu
        ImportControlSettingsMenu      matlab.ui.container.Menu
        ExportImagingSettingsMenu      matlab.ui.container.Menu
        ExportControlSettingsMenu      matlab.ui.container.Menu
        TestSerialConnectionMenu       matlab.ui.container.Menu
        ControlMenu                    matlab.ui.container.Menu
        ResetControlParametersMenu     matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        ReviewMenu                     matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        ClearlogButton                 matlab.ui.control.Button
        SetupAxesPanel                 matlab.ui.container.Panel
        DynamicRangeSlider             matlab.ui.control.Slider
        DynamicRangeSliderLabel        matlab.ui.control.Label
        AcquireInitVolumeLabel         matlab.ui.control.Label
        AcquireButton                  matlab.ui.control.Button
        ElevationSlider                matlab.ui.control.Slider
        ElevationLabel_2               matlab.ui.control.Label
        AzimuthSlider                  matlab.ui.control.Slider
        AzimuthLabel_2                 matlab.ui.control.Label
        RangeSlider                    matlab.ui.control.Slider
        RangeSliderLabel               matlab.ui.control.Label
        raaz_init_axes                 matlab.ui.control.UIAxes
        rael_init_axes                 matlab.ui.control.UIAxes
        azel_init_axes                 matlab.ui.control.UIAxes
        LoglevelDropDown               matlab.ui.control.DropDown
        LoglevelDropDownLabel          matlab.ui.control.Label
        LogDisplay                     mlog.view.LogDisplay
        ProbePanel                     matlab.ui.container.Panel
        ManuallyflipdimsLabel          matlab.ui.control.Label
        ProbechoiceLabel               matlab.ui.control.Label
        ProbeDropDown                  matlab.ui.control.DropDown
        RaButton                       matlab.ui.control.StateButton
        ElButton                       matlab.ui.control.StateButton
        AzButton                       matlab.ui.control.StateButton
        ROISettingsPanel               matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        SingleEllipsoidTab             matlab.ui.container.Tab
        IBSnormboundsPanel             matlab.ui.container.Panel
        SetIBSgeoButton                matlab.ui.control.Button
        azMinEditField_cutoff          matlab.ui.control.NumericEditField
        RangeMinLabel_3                matlab.ui.control.Label
        azMaxEditField_cutoff          matlab.ui.control.NumericEditField
        RangeMaxLabel_3                matlab.ui.control.Label
        elMinEditField_cutoff          matlab.ui.control.NumericEditField
        RangeMinLabel_2                matlab.ui.control.Label
        elMaxEditField_cutoff          matlab.ui.control.NumericEditField
        RangeMaxLabel_2                matlab.ui.control.Label
        rangeMinEditField_cutoff       matlab.ui.control.NumericEditField
        RangeMinLabel                  matlab.ui.control.Label
        rangeMaxEditField_cutoff       matlab.ui.control.NumericEditField
        RangeMaxLabel                  matlab.ui.control.Label
        ControlROIPanel                matlab.ui.container.Panel
        R_raEditField                  matlab.ui.control.NumericEditField
        R_raEditFieldLabel             matlab.ui.control.Label
        R_azEditField                  matlab.ui.control.NumericEditField
        R_azEditFieldLabel             matlab.ui.control.Label
        R_elEditField                  matlab.ui.control.NumericEditField
        R_elEditFieldLabel             matlab.ui.control.Label
        RangeEditField                 matlab.ui.control.NumericEditField
        RangeEditFieldLabel            matlab.ui.control.Label
        AzimuthEditField               matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel          matlab.ui.control.Label
        ElevationEditField             matlab.ui.control.NumericEditField
        ElevationEditFieldLabel        matlab.ui.control.Label
        GammaEditField                 matlab.ui.control.NumericEditField
        GammaEditFieldLabel            matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.NumericEditField
        BetaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphaEditFieldLabel            matlab.ui.control.Label
        AngleLabel                     matlab.ui.control.Label
        RadiiLabel                     matlab.ui.control.Label
        ROICenterLabel                 matlab.ui.control.Label
        MultiEllipsoidTab              matlab.ui.container.Tab
        ROIModeDropDown                matlab.ui.control.DropDown
        ROIModeDropDownLabel           matlab.ui.control.Label
        ShrinkVolumetoROIButton        matlab.ui.control.Button
        InnerRadiiLabel                matlab.ui.control.Label
        Rz_inEditField                 matlab.ui.control.NumericEditField
        Rz_inEditFieldLabel            matlab.ui.control.Label
        Ry_inEditField                 matlab.ui.control.NumericEditField
        Ry_inEditFieldLabel            matlab.ui.control.Label
        Rx_inEditField                 matlab.ui.control.NumericEditField
        Rx_inEditFieldLabel            matlab.ui.control.Label
        TargetDirectoryPanel           matlab.ui.container.Panel
        TrialEditField                 matlab.ui.control.NumericEditField
        TrialEditFieldLabel            matlab.ui.control.Label
        DirectoryLabel                 matlab.ui.control.Label
        SetDirectoryButton             matlab.ui.control.Button
        ImagingSettingsPanel           matlab.ui.container.Panel
        SetmanuallyLabel               matlab.ui.control.Label
        DepthmmEditField               matlab.ui.control.NumericEditField
        DepthmmEditFieldLabel          matlab.ui.control.Label
        FetchinfofromscannerButton     matlab.ui.control.Button
        WindowSigmaEditField           matlab.ui.control.NumericEditField
        WindowSigmaEditFieldLabel      matlab.ui.control.Label
        VoxelstepEditField             matlab.ui.control.NumericEditField
        VoxelstepEditFieldLabel        matlab.ui.control.Label
        FrameRateEditField             matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel        matlab.ui.control.Label
        ElAngledegEditField            matlab.ui.control.NumericEditField
        ElAngledegEditFieldLabel       matlab.ui.control.Label
        AzAngledegEditField            matlab.ui.control.NumericEditField
        AzAngledegEditFieldLabel       matlab.ui.control.Label
        SerialConnectionsPanel         matlab.ui.container.Panel
        resetButton_2                  matlab.ui.control.Button
        resetButton                    matlab.ui.control.Button
        rfoutlight                     matlab.ui.control.Lamp
        ReadyLamp_4Label               matlab.ui.control.Label
        rfcontrollight                 matlab.ui.control.Lamp
        ReadyLamp_3Label               matlab.ui.control.Label
        EnableSwitch_2                 matlab.ui.control.Switch
        EnableSwitch_2Label            matlab.ui.control.Label
        EnableSwitch                   matlab.ui.control.Switch
        EnableSwitchLabel              matlab.ui.control.Label
        VerifyButton_2                 matlab.ui.control.Button
        VerifyButton                   matlab.ui.control.Button
        RFOutputDropDown               matlab.ui.control.DropDown
        RFOutputDropDownLabel          matlab.ui.control.Label
        RFControllerDropDown           matlab.ui.control.DropDown
        RFControllerLabel              matlab.ui.control.Label
        ImagingTab                     matlab.ui.container.Tab
        LoglevelDropDown_imaging       matlab.ui.control.DropDown
        LoglevelDropDown_2Label        matlab.ui.control.Label
        LogDisplay2                    mlog.view.LogDisplay
        ExperimentInfoPanel            matlab.ui.container.Panel
        VolcompletiontimeEditField     matlab.ui.control.NumericEditField
        VolcompletiontimeEditFieldLabel  matlab.ui.control.Label
        AblationvolumesEditField       matlab.ui.control.NumericEditField
        AblationvolumesEditFieldLabel  matlab.ui.control.Label
        ShamvolumesEditField           matlab.ui.control.NumericEditField
        ShamvolumesEditFieldLabel      matlab.ui.control.Label
        ElapsedTimeEditField           matlab.ui.control.NumericEditField
        ElapsedTimeEditFieldLabel      matlab.ui.control.Label
        logCumDecEditField             matlab.ui.control.NumericEditField
        logCumDecEditFieldLabel        matlab.ui.control.Label
        logArtifDecorrEditField        matlab.ui.control.NumericEditField
        logArtifDecorrEditFieldLabel   matlab.ui.control.Label
        EndTrialButton                 matlab.ui.control.Button
        SaveRFButton                   matlab.ui.control.Button
        OverridePanel                  matlab.ui.container.Panel
        StopimagingButton              matlab.ui.control.Button
        ActivateRFgenButton            matlab.ui.control.Button
        MindecdisplayEditField         matlab.ui.control.NumericEditField
        MindecdisplayEditFieldLabel    matlab.ui.control.Label
        DecorrDynamicRangeSlider_imaging  matlab.ui.control.Slider
        DynamicRangeSlider_2Label_2    matlab.ui.control.Label
        ImagingPanel                   matlab.ui.container.Panel
        PreablationvolsEditField       matlab.ui.control.NumericEditField
        PreablationvolsEditFieldLabel  matlab.ui.control.Label
        StartablationautomaticallyCheckBox  matlab.ui.control.CheckBox
        RecordSingleVolumeButton       matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        StartAutomaticRecordingButton  matlab.ui.control.Button
        ReadyLamp_2                    matlab.ui.control.Lamp
        ReadyLamp_2Label               matlab.ui.control.Label
        DynamicRangeSlider_imaging     matlab.ui.control.Slider
        BmodeDynamicRangeLabel         matlab.ui.control.Label
        AzimuthSlider_imaging          matlab.ui.control.Slider
        AzimuthLabel_3                 matlab.ui.control.Label
        ElevationSlider_imaging        matlab.ui.control.Slider
        ElevationLabel_3               matlab.ui.control.Label
        RangeSlider_imaging            matlab.ui.control.Slider
        RangeSlider_2Label             matlab.ui.control.Label
        ImagingcontrolsPanel           matlab.ui.container.Panel
        DecorrelationSettingsPanel     matlab.ui.container.Panel
        ControlThreshEditField         matlab.ui.control.NumericEditField
        ControlThreshEditFieldLabel    matlab.ui.control.Label
        NumshamEditField               matlab.ui.control.NumericEditField
        NumshamEditFieldLabel          matlab.ui.control.Label
        MotioncorrectCheckBox          matlab.ui.control.CheckBox
        LocalCheckBox                  matlab.ui.control.CheckBox
        GlobalCheckBox                 matlab.ui.control.CheckBox
        TrialtypeButtonGroup           matlab.ui.container.ButtonGroup
        ControlModeButton              matlab.ui.control.RadioButton
        MonitoringModeButton           matlab.ui.control.RadioButton
        rfout_axes                     matlab.ui.control.UIAxes
        raaz_imaging_axes              matlab.ui.control.UIAxes
        rael_imaging_axes              matlab.ui.control.UIAxes
        azel_imaging_axes              matlab.ui.control.UIAxes
        decorr_imaging_axes            matlab.ui.control.UIAxes
        ReviewTab                      matlab.ui.container.Tab
        AzimuthSlider_review           matlab.ui.control.Slider
        AzimuthLabel                   matlab.ui.control.Label
        ElevationSlider_review         matlab.ui.control.Slider
        ElevationLabel                 matlab.ui.control.Label
        RangeSlider_review             matlab.ui.control.Slider
        RangeSlider_2Label_2           matlab.ui.control.Label
        Panel2_2                       matlab.ui.container.Panel
        Panel2                         matlab.ui.container.Panel
        SelectDataDropDown             matlab.ui.control.DropDown
        SelectDataDropDownLabel        matlab.ui.control.Label
        forward1button                 matlab.ui.control.Button
        back1button                    matlab.ui.control.Button
        endbutton                      matlab.ui.control.Button
        beeginbutton                   matlab.ui.control.Button
        ReviewControlPanel             matlab.ui.container.Panel
        FrameRateEditField_2           matlab.ui.control.NumericEditField
        FrameRateEditField_2Label      matlab.ui.control.Label
        DecorrThreshEditField_2        matlab.ui.control.EditField
        DecorrThreshEditField_2Label   matlab.ui.control.Label
        NumberofVolumesEditField       matlab.ui.control.EditField
        NumberofVolumesEditFieldLabel  matlab.ui.control.Label
        SigmaEditField_2               matlab.ui.control.EditField
        SigmaEditField_2Label          matlab.ui.control.Label
        ThreshEditField                matlab.ui.control.NumericEditField
        ThreshEditFieldLabel           matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        SigmaEditFieldLabel            matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        LoadExperimentfromfileButton   matlab.ui.control.Button
        DirectoryLabel_2               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        LoadExperimentButton           matlab.ui.control.Button
        review_decorr_axes             matlab.ui.control.UIAxes
        rael_review_axes               matlab.ui.control.UIAxes
        raaz_review_axes               matlab.ui.control.UIAxes
        azel_review_axes               matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        LevelMenu                      matlab.ui.container.Menu
        DefaultMenu                    matlab.ui.container.Menu
        DebugMenu                      matlab.ui.container.Menu
        WarningMenu                    matlab.ui.container.Menu
        ErrorMenu                      matlab.ui.container.Menu
        CriticalMenu                   matlab.ui.container.Menu
        ClearMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = public)
        experiment; % Main experiment class
        isoDisplay;
        serialDevList; 
        currDisplayVol;
        recordingActive;
        paused; 
        imagingMode=1; 
        serialOutObj;
        serialInObj; 
        rfDatOut; 
        serialInBaudRate; 
        serialInTime;
        volCompTime;
        ablationActive=false;
        logObj;
        MessageListener;
    end
    
    methods (Access = public)
        function changeUIExperimentState(app)
            experimentState = app.experiment.machineState; 
            switch experimentState
                case 0 % init
                    activeComponents = {app.TargetDirectoryPanel};
                    inactiveComponents = {app.ProbePanel, app.ImagingSettingsPanel, ...
                                          app.SerialConnectionsPanel, app.ControlROIPanel, ...
                                          app.SetupAxesPanel};
                case 1 % Created experiment obj
                    activeComponents = {};
                    inactiveComponents = {};
                case 2
                    activeComponents = {};
                    inactiveComponents = {};
                case 3
                    activeComponents = {};
                    inactiveComponents = {};
                case 4
                    activeComponents = {};
                    inactiveComponents = {};
                case 5
                    activeComponents = {};
                    inactiveComponents = {};
                case 6
                    activeComponents = {};
                    inactiveComponents = {};
                otherwise
                    
            end
            for currUIElem = 1:numel(activeComponents)
                app.(activeComponents{currUIElem}).Enable = true;
            end
            
            for currUIElem = 1:numel(inactiveComponents)
                app.(activeComponents{currUIElem}).Enable = false;
            end
        end

        %% Init objects
        function initObjLogger(app)
            % Load logger module
            logModulePath=fullfile('..','modules','advanced-logger','advancedLogger');
            addpath(logModulePath);
            % create logger
            app.logObj = mlog.Logger("EchoDecorrGUI");
            %app.MessageListener = event.listener(app.logObj, ...
    %"MessageReceived", @(src,evt)onMessageReceived(app,src,evt) );
            app.logObj.trace('Initialized log');
        end
        function initObjExperimentDirectoryUI(app)
            app.experiment = EchoDecorrPkg.ExperimentClass(); 
            try
                app.experiment.initDataFolderGUI();
                updateDirectory(app);
                app.logObj.message(strcat('Created experiment object with directory: ',app.experiment.dataFolder))
            catch
                app.logObj.warning('Exited without setting directory')
            end
        end
        function initObjSerialIn(app,serialString,baudRate)
            app.serialInObj = serialport(serialString,baudRate);
            app.rfDatOut = [];
            app.serialInBaudRate = baudRate/2/8;
            app.logObj.info('Set up pump microcontroller serial connection')
        end
        %% setters
        function setImagingParams(app)
            % Get params from GUI
            thisphimax = deg2rad(app.ElAngledegEditField.Value/2);
            thisphimin = -thisphimax;
            thisthetamax = deg2rad(app.AzAngledegEditField.Value/2);
            thisthetamin = -thisphimax;
            thiscartScalingFactor = app.VoxelstepEditField.Value; 
            thisFrameRate = app.FrameRateEditField.Value; 
            thissigma = app.WindowSigmaEditField.Value; 
            % Init in the main experiment object
            app.experiment.setImagingParams(thisthetamin,thisthetamax,thisphimin,thisphimax,thiscartScalingFactor,thisFrameRate,thissigma);
            app.logObj.message('Updated imaging parameters');
        end
        function setROIParams(app)
            y0 = app.AzimuthEditField.Value;
            x0 = app.ElevationEditField.Value; 
            z0 = app.RangeEditField.Value; 
            Rx_in = app.Rx_inEditField.Value;
            Ry_in = app.Ry_inEditField.Value;
            Rz_in = app.Rz_inEditField.Value;
            Rx_out = app.R_raEditField.Value;
            Ry_out = app.R_azEditField.Value;
            Rz_out = app.R_elEditField.Value;
            alphaAng = app.AlphaEditField.Value; 
            betaAng = app.BetaEditField.Value; 
            gammaAng = app.GammaEditField.Value; 
            app.experiment.setROIParams(x0,y0,z0,Rx_out,Ry_out,Rz_out,Rx_in,Ry_in,Rz_in,alphaAng,gammaAng,betaAng);
            updateInitAxes(app);
            app.logObj.message('Updated ROI parameters');
        end
        function setIBSParam(app)
            IBSrMax=app.rangeMaxEditField_cutoff.Value;
            IBSrMin=app.rangeMinEditField_cutoff.Value;
            IBSElMax=app.elMaxEditField_cutoff.Value;
            IBSElMin=app.elMinEditField_cutoff.Value;
            IBSAzMax=app.azMaxEditField_cutoff.Value;
            IBSAzMin=app.azMinEditField_cutoff.Value;
            app.experiment.setIBSparam(IBSrMin,IBSrMax,IBSAzMin,IBSAzMax,IBSElMin,IBSElMax)
            app.logObj.message('Updated IBS parameters');
        end
        
        %% Init UI elements
        function initInitAxes(app)
            [zMax,yMax,xMax,~] = size(app.experiment.initDataSet.rawData_cart);
            app.ElevationSlider.Limits = [1, xMax];
            app.AzimuthSlider.Limits = [1,yMax];
            app.RangeSlider.Limits = [1,zMax];
            app.ElevationSlider.Value= floor(xMax/2);
            app.AzimuthSlider.Value = floor(yMax/2);
            app.RangeSlider.Value = floor(zMax/2);
            app.logObj.trace('Init axes limits (setup)');
        end
        function initImagingAxes(app)
            [zMax,yMax,xMax,~] = size(app.experiment.initDataSet(1).rawData_cart);
            %app.experiment.initDataSet(1).rawData_cart.x_range;
            app.ElevationSlider_imaging.Limits = [1, xMax];
            app.AzimuthSlider_imaging.Limits = [1,yMax];
            app.RangeSlider_imaging.Limits = [1,zMax];
            app.ElevationSlider_imaging.Value= floor(xMax/2);
            app.AzimuthSlider_imaging.Value = floor(yMax/2);
            app.RangeSlider_imaging.Value = floor(zMax/2);
            app.logObj.trace('Init axes limits (imaging)');
        end
        %% Update UI elements
        function updateInitAxes(app)
            %%% vars %%%
            %% Get Slider Values
            bMode_scale = 20*log10(.25); 
            elev = floor(app.ElevationSlider.Value);
            azim = floor(app.AzimuthSlider.Value); 
            ra = floor(app.RangeSlider.Value); 
            dynamicRange = app.DynamicRangeSlider.Value; 
            %% get volume for display
            displayVol = 20*log10(abs(squeeze(app.experiment.initDataSet.rawData_cart(:,:,:,1)))); 
            [zSize,ySize,xSize] = size(displayVol);
            %% get ROI 
            
            [az_el_overlay,ra_az_overlay,ra_el_overlay]=app.generateROIOverlay(ra,azim,elev,true);
            
            %%% Plots %%%
            %% az-el %%
            cla(app.azel_init_axes);
            hold(app.azel_init_axes,'on');
                BmodeIn = squeeze(displayVol(ra,:,:));
                BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
                BmodeScaled(BmodeScaled<0) = 0;
                BmodeScaled(BmodeScaled>1) = 1;
                imagesc(app.azel_init_axes,app.experiment.yVec,app.experiment.xVec,BmodeScaled);
                colormap(app.azel_init_axes,gray);
                green = zeros(xSize,ySize,3);
                green(:,:,2) = 1; 
                h = imshow(green, 'XData',app.experiment.yVec,'YData',app.experiment.xVec,'Parent',app.azel_init_axes);
                axis(app.azel_init_axes,'on')
                set(h,'AlphaData',az_el_overlay);
                xlim(app.azel_init_axes,[app.experiment.xVec(1),app.experiment.xVec(end)]);
                ylim(app.azel_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
            hold(app.azel_init_axes,'off')
            
            %% Ra - Az
            cla(app.raaz_init_axes);
            hold(app.raaz_init_axes,'on');
                BmodeIn = squeeze(displayVol(:,:,elev))';
                BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
                BmodeScaled(BmodeScaled<0) = 0;
                BmodeScaled(BmodeScaled>1) = 1;
                
                imagesc(app.raaz_init_axes,app.experiment.xVec,app.experiment.zVec,BmodeScaled');
                colormap(app.raaz_init_axes,gray);
                green = zeros(zSize,xSize,3);
                green(:,:,2) = 1; 
                k = imshow(green, 'XData',app.experiment.xVec,'YData',app.experiment.zVec,'Parent',app.raaz_init_axes);
                axis(app.raaz_init_axes,'on')
                
                set(k,'AlphaData',ra_az_overlay);
                
                xlim(app.raaz_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
                ylim(app.raaz_init_axes,[app.experiment.zVec(1),app.experiment.zVec(end)]);
                set(app.raaz_init_axes,'YDir','reverse') 
                
            hold(app.raaz_init_axes,'off')
            
            %% Ra - El 
            cla(app.rael_init_axes);
            hold(app.rael_init_axes,'on');
                BmodeIn = squeeze(displayVol(:,azim,:))';
                BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
                BmodeScaled(BmodeScaled<0) = 0;
                BmodeScaled(BmodeScaled>1) = 1;
                imagesc(app.rael_init_axes,app.experiment.yVec,app.experiment.zVec,(BmodeScaled'))
                colormap(app.rael_init_axes,gray);
                green = zeros(zSize,ySize,3);
                green(:,:,2) = 1; 
                l = imshow(green, 'XData',app.experiment.yVec,'YData',app.experiment.zVec,'Parent',app.rael_init_axes);
                axis(app.rael_init_axes,'on')
                
                set(l,'AlphaData',ra_el_overlay);
                
                xlim(app.rael_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
                ylim(app.rael_init_axes,[app.experiment.zVec(1),app.experiment.zVec(end)]);
            hold(app.rael_init_axes,'off')
            app.logObj.debug('Updated init axes');
        end
        function updateDirectory(app)
            app.DirectoryLabel.Text = app.experiment.dataFolder; 
        end
        function updateImagingAxis(app)
            initImagingAxes(app)
            %%% vars %%%
            bMode_scale = 20*log10(.25); 
            % Get Slider Values
            elev = floor(app.ElevationSlider_imaging.Value);
            azim = floor(app.AzimuthSlider_imaging.Value); 
            ra = floor(app.RangeSlider_imaging.Value); 
            %% get volume for display
            if ~isempty(app.experiment.ultrasoundDataSeries) 
                displayVol = 20*log10(abs(squeeze(app.experiment.ultrasoundDataSeries(end).rawData_cart(:,:,:,1)))); 
                decorrVol = app.experiment.cumulativeDecorr;
            else
                app.logObj.warning('Attempted display with no recorded datasets');
                return
            end
            %% get ROI 
            [az_el_overlay,ra_az_overlay,ra_el_overlay]=app.generateROIOverlay(ra,azim,elev,false);
            %% % Plots %%%
            decorrVol(decorrVol<=0)=realmin;
            decorrVol=log10(decorrVol);
            %% az-el %%
            BmodeIn = squeeze(displayVol(ra,:,:));
            decorrIn = squeeze(decorrVol(ra,:,:));
            hybrid_RGB_azel=app.generateDecorrOverlay(BmodeIn, decorrIn,bMode_scale, az_el_overlay);
            image(app.azel_imaging_axes,app.experiment.yVec,app.experiment.xVec,hybrid_RGB_azel);
            
            %% Ra - Az
            cla(app.raaz_imaging_axes);
            BmodeIn = squeeze(displayVol(:,:,elev));
            decorrIn = squeeze(decorrVol(:,:,elev));
            hybrid_RGB_raaz=app.generateDecorrOverlay(BmodeIn, decorrIn,bMode_scale, ra_az_overlay);
            image(app.raaz_imaging_axes,app.experiment.yVec,app.experiment.zVec,hybrid_RGB_raaz);
            %% Ra - El 
            cla(app.rael_imaging_axes);
            BmodeIn = squeeze(displayVol(:,azim,:));
            decorrIn = squeeze(decorrVol(:,azim,:));
            hybrid_RGB_rael=app.generateDecorrOverlay(BmodeIn, decorrIn,bMode_scale, ra_el_overlay);
            image(app.rael_imaging_axes,app.experiment.yVec,app.experiment.zVec,hybrid_RGB_rael);
            %% scale images
            axis(app.rael_imaging_axes,'image')
            axis(app.raaz_imaging_axes,'image')
            axis(app.azel_imaging_axes,'image')
            app.logObj.trace('Updated imaging axis');
        end
        function updateDecorrAvgPlot(app)
            usTimeArr = app.experiment.getTimeArr();
            if isempty(usTimeArr)
                app.logObj.warning('No ultrasound data available to plot');
                return
            end
            if length(app.experiment.ultrasoundDataSeries)<2
                app.logObj.warning('No ultrasound data available to plot');
                return
            end
            usTimeArr = [usTimeArr{:}];
            elapsedTime = minutes(usTimeArr(end) - usTimeArr(1));
            app.ElapsedTimeEditField.Value = elapsedTime;
            plot(app.decorr_imaging_axes,usTimeArr,log10(app.experiment.decorrAverageSeriesROI), ...
                usTimeArr,log10(app.experiment.decorrAverageSeries)); 
            legend(app.decorr_imaging_axes,'Average in ROI','Average in volume','Location','southeast')
            xlabel(app.decorr_imaging_axes,'Volume')
            ylabel(app.decorr_imaging_axes,'Decorrelation')
            ylim(app.decorr_imaging_axes,[-6 0])
            app.logObj.trace('Updated decorr average plot');
            drawnow;
        end
        function updateSerialOutputPlot(app)
            if isempty(app.rfDatOut)
                app.logObj.debug('No serial data available to plot')
                return
            end
            rfTimeDat = arrayfun(@(x) x.time, app.rfDatOut,'UniformOutput',false);
            rfTimeDat =  [rfTimeDat{:}];
            rfTempDat = arrayfun(@(x) x.t1, app.rfDatOut,'UniformOutput',false);
            rfTempDat =  [rfTempDat{:}];
            plot(app.rfout_axes, ...
                rfTimeDat, rfTempDat);
            app.logObj.trace('Updated serial plot')
        end
        function updateCurrExperimentInfo(app)
            if ~isempty(app.experiment.ultrasoundDataSeries)
                app.ShamvolumesEditField.Value=sum(app.experiment.isShamMask);
                app.AblationvolumesEditField.Value = sum(~app.experiment.isShamMask);
                app.VolcompletiontimeEditField.Value = (app.volCompTime);
                app.logCumDecEditField.Value = log10(app.experiment.decorrAverageSeriesROI(end));
                app.logObj.trace('Updated Experiment runtime info')
                drawnow;
                tau = 1000*app.experiment.interFrameTime;
                if app.MotioncorrectCheckBox.Value
                    app.logArtifDecorrEditField.Value=log10((1/tau)*mean(app.experiment.cumulativeShamDecorr(app.experiment.ROIMap(:))));
                end
            else
                app.logObj.warning('Experiment runtime query returned empty list')
            end
        end
        function updateSerialDevices(app)
            app.serialDevList = serialportlist;
            app.RFControllerDropDown.Items = app.serialDevList; 
            app.RFOutputDropDown.Items = app.serialDevList; 
            app.logObj.debug('Updated serial device list');
        end
        function updateImagingGUIElements(app)
            app.updateImagingAxis();
            app.updateCurrExperimentInfo()
            app.updateDecorrAvgPlot()
            app.updateSerialOutputPlot()
            drawnow;
            app.logObj.trace('Updated imaging axis gui elements');
        end
        %%
        function exportExperiment(app)
            app.logObj.debug('Starting experiment export')
            exper2export = app.experiment;
            filename = inputdlg('Enter File name');
            save(strcat(filename{1},'.mat'), 'exper2export')
        end
        function loadExperimentFromFile(app)
            myFile = uigetfile();
            inp = load(myFile);
            app.experiment  = inp.exper2export; 
        end

        function getRFData(app)
            if ~isempty(app.experiment.inSerialObj)
                try
                    [app.experiment.inSerialObj,sBlock] = app.experiment.inSerialObj.getSerialBlock();
                    app.rfDatOut = [app.rfDatOut,sBlock];
                    app.logObj.trace('Processed async RF block');
                catch
                    app.logObj.warning('Could not parse serial data');
                end
            else
                app.logObj.trace('Input serial object was never created');
            end
        end
    end
    
    
    methods (Access = private)
        %% Helpers
        %% Generate images
        function hybrid_RGB = generateDecorrOverlay(app, BmodeIn, decorrIn,bMode_scale, overlayIn)
            % Scale Decorr
            minDecorrVal = app.MindecdisplayEditField.Value;
            belowMinDecorr = decorrIn < minDecorrVal;
            decDynRange = app.DecorrDynamicRangeSlider_imaging.Value;
            decDynRangeBMap= @(x) (20 /decDynRange).* (x - minDecorrVal);
            decorrScaled = decDynRangeBMap(decorrIn); 
            decorrScaled(belowMinDecorr)=0;
            % Scale Bmode
            dynamicRange = app.DynamicRangeSlider_imaging.Value; 
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            BmodeScaled(BmodeScaled<0) = 0;
            BmodeScaled(BmodeScaled>1) = 1;
            % construct images
            % construct RGB image from B-mode background
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            % define transparency as proportional amount of overlay
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(belowMinDecorr)=bmode_RGB(belowMinDecorr);
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            % Overlay region as green pixels
            green = hybrid_RGB(:,:,2);
            green(overlayIn) = 1; 
            hybrid_RGB(:,:,2) = green; 
        end
        function [az_el,ra_az,ra_el]=generateROIOverlay(app,ra,azim,elev,showIBSGeo)
            % return binary slices at ra/az/el where points around the ROI
            % are ones 
            if(isempty(app.experiment.ROIMap))
                [raSize,azSize,elSize,~]=size(app.experiment.initDataSet.rawData_cart);
                az_el=zeros(azSize,elSize);
                ra_az=zeros(raSize,azSize);
                ra_el=zeros(raSize,elSize);
                app.logObj.warning('Empty ROI overlay');
                return
            end
            %
            az_el=edge(squeeze(app.experiment.ROIMap(ra,:,:)));
            ra_az=edge(squeeze(app.experiment.ROIMap(:,:,elev)));
            ra_el=edge(squeeze(app.experiment.ROIMap(:,azim,:)));
            if showIBSGeo
                % get bounds
                validRa=find(app.experiment.zVec>app.rangeMinEditField_cutoff.Value & app.experiment.zVec<app.rangeMaxEditField_cutoff.Value);
                    minRaInd=validRa(1);maxRaInd=validRa(end);
                validAz=find(app.experiment.yVec>app.azMinEditField_cutoff.Value & app.experiment.yVec<app.azMaxEditField_cutoff.Value);
                    minAzInd=validAz(1);maxAzInd=validAz(end);
                validEl=find(app.experiment.xVec>app.elMinEditField_cutoff.Value & app.experiment.xVec<app.elMaxEditField_cutoff.Value);
                    minElInd=validEl(1);maxElInd=validEl(end);
                % add to mask
                az_el(:,minElInd)=1;az_el(:,maxElInd)=1;
                az_el(minAzInd,:)=1;az_el(maxAzInd,:)=1;
                ra_az(:,minAzInd)=1;ra_az(:,maxAzInd)=1;
                ra_az(minRaInd,:)=1;ra_az(maxRaInd,:)=1;
                ra_el(:,minElInd)=1;ra_el(:,maxElInd)=1;
                ra_el(minRaInd,:)=1;ra_el(maxRaInd,:)=1;
            end
        end
        %% Wrap object function calls
        function wrapAcquireInit(app)
            app.AcquireButton.Text = "Processing";
            setImagingParams(app)
            app.experiment.getInitDataSet_c();
            app.logObj.info('Processesing initial volume')
            initInitAxes(app);
            app.AcquireButton.Text = "Request";
            updateInitAxes(app)
        end
        function wrapGetSingleNextDataSet(app)
            %
            app.logObj.debug('Starting wrapped nextDataSet call');
            setInactiveExperimentGUIElements(app)
            % Get next data set
            tic; 
                succ = app.experiment.nextDataSet(); 
                app.volCompTime =...
            toc;
            
            if succ
                app.logObj.debug('Data set successfully processed');
                app.logObj.detail(sprintf('Recorded volume %i, completion time=%.3f',length(app.experiment.ultrasoundDataSeries),app.volCompTime));
            else
                app.logObj.debug('No available datasets to process');
            end
            app.setActiveExperimentGUIElements();
            updateImagingAxis(app);
            app.logObj.message('Finished capture of single volume');
        end
        function wrapActuateRFGenerator(app)
            if app.experiment.outSerialObjExists
                try
                    app.experiment.sendSerialData();
                    app.logObj.info('Sent actuation signal to RF generator');
                catch
                    app.logObj.error('Could not actuate RF generator!');
                end
            else
                app.logObj.trace('Serial connection was not set');
            end
        end
        function wrapInitMotionCorrection(app)
            try
                app.experiment.initMotionCorrection(app.NumshamEditField.Value);
                app.logObj.info('Initialized cumulative sham decorr as the %i most recent datasets');
            catch
                app.logObj.error('Could not initialize cumulative sham decorr')
            end
        end
        function wrapFetchInfo(app)
            app.experiment.getInitInfo;
            app.FrameRateEditField.Value = app.experiment.frameRate;
            app.AzAngledegEditField.Value = app.experiment.phiRange;
            app.ElAngledegEditField.Value = app.experiment.thetaRange;
            %app.RangeEditField.Value=app.experiment.rmax;
            app.logObj.info('Fetched info from init dataset')
            app.logObj.detail(sprintf('Data from scanner infofile: Framerate=%i,azimuth angle=%.4f, elevation angle=%.4f',app.experiment.frameRate,app.experiment.phiRange,app.experiment.thetaRange))
        end
        %% UI helpers
        function setActiveExperimentGUIElements(app)
            app.StartAutomaticRecordingButton.Text = "Active";
            app.recordingActive=true;
            app.ReadyLamp_2.Color = [1 0 0];
        end
        function setInactiveExperimentGUIElements(app)
            app.StartAutomaticRecordingButton.Text = "Record Volume";
            app.recordingActive=true;
            app.ReadyLamp_2.Color = [0 1 0];
        end
        function setImagingMode(app)
            selectedButton = app.TrialtypeButtonGroup.SelectedObject;
            opts = {'Monitoring Mode','Control Mode'};
            for i = 1:numel(opts)
                if strcmp(opts{i}, selectedButton.Text)
                    app.imagingMode = i;
                end
            end
            app.logObj.info(strcat('Changed mode to: ',selectedButton.Text));
        end
        %% Logic helpers
        function stopImaging(app)
            if app.recordingActive
                app.recordingActive = false;
                app.wrapActuateRFGenerator();
                app.logObj.info('Interrupted experiment loop with stop command')
            else
                app.logObj.warning('Stop attempted, but experiment was already stopped')
            end
        end
        function app.togglePause(app)
            if app.paused 
                app.PauseButton.Text = "pause";
                app.logObj.message('Interrupted experiment loop to unpause')
            else
                app.PauseButton.Text = "unpause";
                app.logObj.message('Interrupted experiment loop to pause')
            end
            app.paused = ~app.paused;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initObjLogger()
            updateSerialDevices(app);
        end

        % Button pushed function: SetDirectoryButton
        function SetDirectoryButtonPushed(app, event)
            app.initObjExperimentDirectoryUI()
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            setImagingParams(app)
        end

        % Value changed function: AzAngledegEditField
        function AzAngledegEditFieldValueChanged(app, event)
            setImagingParams(app)
        end

        % Value changed function: ElAngledegEditField
        function ElAngledegEditFieldValueChanged(app, event)
            setImagingParams(app)
        end

        % Value changed function: VoxelstepEditField
        function VoxelstepEditFieldValueChanged(app, event)
            setImagingParams(app)
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed2(app, event)
            app.wrapAcquireInit()
        end

        % Value changed function: ElevationSlider
        function ElevationSliderValueChanged(app, event)
            updateInitAxes(app)
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
           updateInitAxes(app)
        end

        % Value changed function: AzimuthSlider
        function AzimuthSliderValueChanged(app, event)
            updateInitAxes(app)
        end

        % Value changed function: ROIModeDropDown
        function ROIModeDropDownValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: R_raEditField
        function R_raEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: ElevationEditField
        function ElevationEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: R_azEditField
        function R_azEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: R_elEditField
        function R_elEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: Rx_inEditField
        function Rx_inEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: Ry_inEditField
        function Ry_inEditFieldValueChanged(app, event)
             setROIParams(app);
        end

        % Value changed function: Rz_inEditField
        function Rz_inEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            setROIParams(app);
        end

        % Button pushed function: RecordSingleVolumeButton
        function RecordSingleVolumeButtonPushed(app, event)
            app.wrapGetSingleNextDataSet();
        end

        % Value changed function: ControlThreshEditField
        function ControlThreshEditFieldValueChanged(app, event)
            app.experiment.setControlParams(app.ControlThreshEditField.Value);
        end

        % Callback function
        function sigmaEditFieldValueChanged(app, event)
            app.setImagingParams()
        end

        % Value changed function: ElevationSlider_imaging
        function ElevationSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: AzimuthSlider_imaging
        function AzimuthSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changing function: AzimuthSlider_imaging
        function AzimuthSlider_imagingValueChanging(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: RangeSlider_imaging
        function RangeSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Callback function
        function PlaceROIraazButtonPushed(app, event)
            setROIGraphically(app,app.raaz_init_axes,'raaz')
        end

        % Button pushed function: ShrinkVolumetoROIButton
        function ShrinkVolumetoROIButtonPushed(app, event)
            app.experiment.defineGridBounds();
            updateInitAxes(app);
        end

        % Button pushed function: LoadExperimentButton
        function LoadExperimentButtonPushed(app, event)
            initReviewPage(app)
            app.currDisplayVol = 1; 
        end

        % Value changed function: RangeSlider_review
        function RangeSlider_reviewValueChanged(app, event)
            updateReviewAxis(app);
        end

        % Value changed function: AzimuthSlider_review
        function AzimuthSlider_reviewValueChanged(app, event)
            updateReviewAxis(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportExperiment(app);
        end

        % Button pushed function: LoadExperimentfromfileButton
        function LoadExperimentfromfileButtonPushed(app, event)
            loadExperimentFromFile(app); 
        end

        % Value changed function: SelectDataDropDown
        function SelectDataDropDownValueChanged(app, event)
            value = app.SelectDataDropDown.Value;
            app.currDisplayVol = str2num(value{1});
        end

        % Button pushed function: StartAutomaticRecordingButton
        function StartAutomaticRecordingButtonPushed(app, event)
            
            app.logObj.message('Starting automatic acquisition');
            app.setIBSParam();
            app.experiment.setMotCorrParams(app.MotioncorrectCheckBox.Value,app.NumshamEditField.Value, app.PreablationvolsEditField.Value);
            if app.ControlModeButton.Value
                app.logObj.detail('Automatic control mode is active');
            else
                app.logObj.detail('Monitoring mode is active');
            end
            if app.MotioncorrectCheckBox.Value
                app.logObj.info(sprintf('Motion correction is on, will collect %i sham data sets',app.NumshamEditField.Value));
            end
            if app.StartablationautomaticallyCheckBox.Value
                app.logObj.info(sprintf('Starting ablation automatically after %i datasets',app.NumberofVolumesEditField.Value));
            end
            app.setActiveExperimentGUIElements();
            app.recordingActive = true;
            hasReadyDataSet=false;
            while app.recordingActive 
                app.logObj.trace('Main loop begin:')
                % pre ablation volumes
                app.getRFData();
                hasReadyDataSet=app.experiment.newDataSetReady();
                if ~hasReadyDataSet
                    app.logObj.trace('Experiment object reports that there are no datasets ready');
                else
                    hasReadyDataSet=false;
                    app.logObj.detail('Found data set');
                    if length(app.experiment.ultrasoundDataSeries) < app.PreablationvolsEditField.Value
                        app.logObj.message('Processing new pre-ablation dataset');
                        tic; 
                            app.experiment.nextShamDataSet(); 
                            app.volCompTime=...
                        toc;
                        app.logObj.info(sprintf('Completed pre-ablation dataset %i in %.4f seconds',length(app.experiment.ultrasoundDataSeries),app.volCompTime));
                        app.updateImagingGUIElements()
                        continue;
                    elseif ~app.ablationActive
                        % Start ablation
                        app.logObj.info(sprintf('Starting ablation automatically after %i pre-ablation datasets',length(app.experiment.ultrasoundDataSeries)));
                        
                        app.logObj.debug('Toggled ablation active flag to true');
                        if app.StartablationautomaticallyCheckBox.Value
                            app.wrapActuateRFGenerator();
                            app.ablationActive=true;
                        end
                        %if app.experiment.isMotionCorrected
                        %    app.wrapInitMotionCorrection();
                        %end
                    end
                    app.logObj.debug('Processing next data set. Available dataset will be treated as a during ablation dataset');
                    tic; 
                        app.experiment.nextDataSet(); 
                        app.volCompTime=...
                    toc;
                    app.logObj.info(sprintf('Completed ablation dataset %i in %.4f seconds',length(app.experiment.ultrasoundDataSeries),app.volCompTime));
                    if app.imagingMode == 2 && app.experiment.decorrExceedsThresh()
                        app.logObj.info('Control threshold exceeded, sending stop signal');
                        app.wrapActuateRFGenerator();
                        app.recordingActive = false; 
                        app.ablationActive = false; 
                        app.logObj.debug('Set recording and ablation flags to false');
                    end
                    app.updateImagingGUIElements()
                    app.logObj.trace('Main loop end');
                    end
                end
                app.logObj.info('Stopped automatic acquisition');
                app.setInactiveExperimentGUIElements();
                app.updateImagingGUIElements();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.togglePause();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.recordingActive
                app.recordingActive = false;
                app.wrapActuateRFGenerator();
                drawnow;
                app.logObj.info('Interrupted experiment loop with stop command')
            else
                app.logObj.warning('Stop attempted, but experiment was already stopped')
            end
        end

        % Selection changed function: TrialtypeButtonGroup
        function TrialtypeButtonGroupSelectionChanged(app, event)
            app.setImagingMode();
        end

        % Value changed function: RFControllerDropDown
        function RFControllerDropDownValueChanged(app, event)
            value = app.RFControllerDropDown.Value;
            app.experiment.setSerialOutName(value);
        end

        % Value changed function: EnableSwitch_2
        function EnableSwitch_2ValueChanged(app, event)
            value = app.EnableSwitch_2.Value;
            if strcat(value,'On')
                try
                    app.experiment.setUpSerialOutConnection();
                    app.rfcontrollight.Color = [0,1,0];
                catch 
                    app.rfcontrollight.Color = [1,0,0];
                end
            else
                app.experiment.removeSerialConnection();
            end
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            app.experiment.sendSerialData();
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            instrreset;
            app.rfcontrollight.Color = [1,1,0];
            app.EnableSwitch_2.Value = 'Off';
        end

        % Button pushed function: FetchinfofromscannerButton
        function FetchinfofromscannerButtonPushed(app, event)
            app.wrapFetchInfo()
        end

        % Value changed function: DynamicRangeSlider
        function DynamicRangeSliderValueChanged(app, event)
            app.updateInitAxes()
        end

        % Value changed function: DynamicRangeSlider_imaging
        function DynamicRangeSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if strcat(value,'On')
                try
                    app.experiment.setUpSerialInConnection();
                    app.experiment.inSerialObj.initSerialBlocks();
                    app.rfoutlight.Color = [0,1,0];
                    app.rfDatOut = [];
                catch 
                    app.rfoutlight.Color = [1,0,0];
                end
            else
                app.experiment.removeSerialConnection();
            end
        end

        % Value changed function: RFOutputDropDown
        function RFOutputDropDownValueChanged(app, event)
            value = app.RFOutputDropDown.Value;
            app.experiment.setSerialInName(value);
        end

        % Button pushed function: EndTrialButton
        function EndTrialButtonPushed(app, event)
            app.logObj.message('Processing experiment for export...')
            try
                app.experiment.inSerialObj.cleanUp();
                app.logObj.debug('Cleaning up serial interface')
            catch
                app.logObj.warning('Could not clean up serial object')
            end
            app.logObj.debug('Starting experiment export')
            outDat = app.experiment.saveObj_withrf(app.rfDatOut);
            outFileName = strcat('FullExp_',date,'_',num2str(app.TrialEditField.Value),'.mat');
            save(outFileName,'outDat','-v7.3');
            app.logObj.message('Exported experiment to file:')
            app.logObj.detail(sprintf("Saved full experiment data to: %s",outFileName))
        end

        % Button pushed function: SetIBSgeoButton
        function SetIBSgeoButtonPushed(app, event)
            app.setIBSParam();
        end

        % Callback function
        function AcquireButtonPushed(app, event)
            for i = 1:app.NumshamEditField.Value
                app.experiment.addNextShamDataSet_c;
            end
        end

        % Callback function
        function AcquireButton_3Pushed(app, event)
            
        end

        % Value changed function: DecorrDynamicRangeSlider_imaging
        function DecorrDynamicRangeSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: rangeMaxEditField_cutoff
        function rangeMaxEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.setIBSParam();
        end

        % Value changed function: rangeMinEditField_cutoff
        function rangeMinEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.setIBSParam();
        end

        % Value changed function: azMaxEditField_cutoff
        function azMaxEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.setIBSParam();
        end

        % Value changed function: azMinEditField_cutoff
        function azMinEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.setIBSParam();
        end

        % Value changed function: elMaxEditField_cutoff
        function elMaxEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.setIBSParam();
        end

        % Value changed function: elMinEditField_cutoff
        function elMinEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.setIBSParam();
        end

        % Button pushed function: SaveRFButton
        function SaveRFButtonPushed(app, event)
            rfData = app.rfDatOut;
            outFileName = strcat('RFData_',date,'_',num2str(app.TrialEditField.Value),'.mat');
            save(outFileName,'rfData','-v7.3');
            app.logObj.message(strcat('Saved RF Data to: ', outFileName));
        end

        % Button pushed function: StopimagingButton
        function StopimagingButtonPushed(app, event)
            if app.recordingActive
                app.recordingActive = false;
            end
        end

        % Value changed function: GlobalCheckBox
        function GlobalCheckBoxValueChanged(app, event)
            app.experiment.setNormMode(app.GlobalCheckBox.Value,app.LocalCheckBox.Value);
            
        end

        % Value changed function: LocalCheckBox
        function LocalCheckBoxValueChanged(app, event)
            app.experiment.setNormMode(app.GlobalCheckBox.Value,app.LocalCheckBox.Value);
        end

        % Value changed function: PreablationvolsEditField
        function PreablationvolsEditFieldValueChanged(app, event)

        end

        % Value changed function: MotioncorrectCheckBox
        function MotioncorrectCheckBoxValueChanged(app, event)
            app.experiment.isMotionCorrected= app.MotioncorrectCheckBox.Value;
        end

        % Value changed function: LoglevelDropDown
        function LoglevelDropDownValueChanged(app, event)
            app.LogDisplay.LowerDisplayThreshold=app.LoglevelDropDown.Value;
            
        end

        % Value changed function: LoglevelDropDown_imaging
        function LoglevelDropDown_imagingValueChanged(app, event)
            app.LogDisplay2.LowerDisplayThreshold=app.LoglevelDropDown_imaging.Value;
            
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.logObj.clearLog(); drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ResetAppMenu = uimenu(app.FileMenu);
            app.ResetAppMenu.Text = 'Reset App';
            app.ImportImagingSettingsMenu = uimenu(app.FileMenu);
            app.ImportImagingSettingsMenu.Text = 'Import Imaging Settings';
            app.ImportControlSettingsMenu = uimenu(app.FileMenu);
            app.ImportControlSettingsMenu.Text = 'Import Control Settings';
            app.ExportImagingSettingsMenu = uimenu(app.FileMenu);
            app.ExportImagingSettingsMenu.Text = 'Export Imaging Settings';
            app.ExportControlSettingsMenu = uimenu(app.FileMenu);
            app.ExportControlSettingsMenu.Text = 'Export Control Settings';
            app.TestSerialConnectionMenu = uimenu(app.FileMenu);
            app.TestSerialConnectionMenu.Text = 'Test Serial Connection';
            app.ControlMenu = uimenu(app.UIFigure);
            app.ControlMenu.Text = 'Control';
            app.ResetControlParametersMenu = uimenu(app.ControlMenu);
            app.ResetControlParametersMenu.Text = 'Reset Control Parameters';
            app.Menu = uimenu(app.ControlMenu);
            app.ReviewMenu = uimenu(app.UIFigure);
            app.ReviewMenu.Text = 'Review';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SerialConnectionsPanel = uipanel(app.SetupTab);
            app.SerialConnectionsPanel.Title = 'Serial Connections';
            app.RFControllerLabel = uilabel(app.SerialConnectionsPanel);
            app.RFControllerLabel.Text = 'RF Controller ';
            app.RFControllerDropDown = uidropdown(app.SerialConnectionsPanel);
            app.RFControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @RFControllerDropDownValueChanged, true);
            app.RFOutputDropDownLabel = uilabel(app.SerialConnectionsPanel);
            app.RFOutputDropDownLabel.Text = 'RF Output';
            app.RFOutputDropDown = uidropdown(app.SerialConnectionsPanel);
            app.RFOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @RFOutputDropDownValueChanged, true);
            app.VerifyButton = uibutton(app.SerialConnectionsPanel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.VerifyButton_2 = uibutton(app.SerialConnectionsPanel, 'push');
            app.VerifyButton_2.Text = 'Verify';
            app.EnableSwitchLabel = uilabel(app.SerialConnectionsPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.SerialConnectionsPanel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.EnableSwitch_2Label = uilabel(app.SerialConnectionsPanel);
            app.EnableSwitch_2Label.Text = 'Enable';
            app.EnableSwitch_2 = uiswitch(app.SerialConnectionsPanel, 'slider');
            app.EnableSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EnableSwitch_2ValueChanged, true);
            app.ReadyLamp_3Label = uilabel(app.SerialConnectionsPanel);
            app.ReadyLamp_3Label.Text = 'Ready';
            app.rfcontrollight = uilamp(app.SerialConnectionsPanel);
            app.ReadyLamp_4Label = uilabel(app.SerialConnectionsPanel);
            app.ReadyLamp_4Label.Text = 'Ready';
            app.rfoutlight = uilamp(app.SerialConnectionsPanel);
            app.resetButton = uibutton(app.SerialConnectionsPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.resetButton_2 = uibutton(app.SerialConnectionsPanel, 'push');
            app.resetButton_2.Text = 'reset';
            app.ImagingSettingsPanel = uipanel(app.SetupTab);
            app.ImagingSettingsPanel.Title = 'Imaging Settings';
            app.AzAngledegEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.AzAngledegEditFieldLabel.Text = 'Az. Angle (deg)';
            app.AzAngledegEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.AzAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @AzAngledegEditFieldValueChanged, true);
            app.ElAngledegEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.ElAngledegEditFieldLabel.Text = 'El. Angle (deg)';
            app.ElAngledegEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.ElAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @ElAngledegEditFieldValueChanged, true);
            app.FrameRateEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.VoxelstepEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.VoxelstepEditFieldLabel.Text = 'Voxel step';
            app.VoxelstepEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.VoxelstepEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelstepEditFieldValueChanged, true);
            app.VoxelstepEditField.Value = 1;
            app.WindowSigmaEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.WindowSigmaEditFieldLabel.Text = 'Window Sigma';
            app.WindowSigmaEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.WindowSigmaEditField.Value = 3;
            app.FetchinfofromscannerButton = uibutton(app.ImagingSettingsPanel, 'push');
            app.FetchinfofromscannerButton.ButtonPushedFcn = createCallbackFcn(app, @FetchinfofromscannerButtonPushed, true);
            app.FetchinfofromscannerButton.Text = 'Fetch info from scanner';
            app.DepthmmEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.DepthmmEditFieldLabel.Visible = 'off';
            app.DepthmmEditFieldLabel.Text = 'Depth (mm)';
            app.DepthmmEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.DepthmmEditField.Visible = 'off';
            app.SetmanuallyLabel = uilabel(app.ImagingSettingsPanel);
            app.SetmanuallyLabel.Text = 'Set manually';
            app.TargetDirectoryPanel = uipanel(app.SetupTab);
            app.TargetDirectoryPanel.Title = 'Target Directory';
            app.SetDirectoryButton = uibutton(app.TargetDirectoryPanel, 'push');
            app.SetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetDirectoryButtonPushed, true);
            app.SetDirectoryButton.Text = 'Set Directory';
            app.DirectoryLabel = uilabel(app.TargetDirectoryPanel);
            app.DirectoryLabel.Text = 'Directory';
            app.TrialEditFieldLabel = uilabel(app.TargetDirectoryPanel);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialEditField = uieditfield(app.TargetDirectoryPanel, 'numeric');
            app.ROISettingsPanel = uipanel(app.SetupTab);
            app.ROISettingsPanel.Title = 'ROI Settings';
            app.TabGroup2 = uitabgroup(app.ROISettingsPanel);
            app.SingleEllipsoidTab = uitab(app.TabGroup2);
            app.SingleEllipsoidTab.Title = 'Single Ellipsoid';
            app.ControlROIPanel = uipanel(app.SingleEllipsoidTab);
            app.ControlROIPanel.Title = 'Control ROI';
            app.ROICenterLabel = uilabel(app.ControlROIPanel);
            app.ROICenterLabel.Text = 'ROI Center';
            app.RadiiLabel = uilabel(app.ControlROIPanel);
            app.RadiiLabel.Text = 'Radii';
            app.AngleLabel = uilabel(app.ControlROIPanel);
            app.AngleLabel.Text = 'Angle ';
            app.AlphaEditFieldLabel = uilabel(app.ControlROIPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.BetaEditFieldLabel = uilabel(app.ControlROIPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.GammaEditFieldLabel = uilabel(app.ControlROIPanel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.ElevationEditFieldLabel = uilabel(app.ControlROIPanel);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.ElevationEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationEditFieldValueChanged, true);
            app.AzimuthEditFieldLabel = uilabel(app.ControlROIPanel);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.RangeEditFieldLabel = uilabel(app.ControlROIPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.R_elEditFieldLabel = uilabel(app.ControlROIPanel);
            app.R_elEditFieldLabel.Text = 'R_el';
            app.R_elEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.R_elEditField.ValueChangedFcn = createCallbackFcn(app, @R_elEditFieldValueChanged, true);
            app.R_azEditFieldLabel = uilabel(app.ControlROIPanel);
            app.R_azEditFieldLabel.Text = 'R_az';
            app.R_azEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.R_azEditField.ValueChangedFcn = createCallbackFcn(app, @R_azEditFieldValueChanged, true);
            app.R_raEditFieldLabel = uilabel(app.ControlROIPanel);
            app.R_raEditFieldLabel.Text = 'R_ra';
            app.R_raEditField = uieditfield(app.ControlROIPanel, 'numeric');
            app.R_raEditField.ValueChangedFcn = createCallbackFcn(app, @R_raEditFieldValueChanged, true);
            app.IBSnormboundsPanel = uipanel(app.SingleEllipsoidTab);
            app.IBSnormboundsPanel.Title = 'IBS norm bounds';
            app.RangeMaxLabel = uilabel(app.IBSnormboundsPanel);
            app.RangeMaxLabel.Text = 'RangeMax';
            app.rangeMaxEditField_cutoff = uieditfield(app.IBSnormboundsPanel, 'numeric');
            app.rangeMaxEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @rangeMaxEditField_cutoffValueChanged, true);
            app.rangeMaxEditField_cutoff.Value = 1000;
            app.RangeMinLabel = uilabel(app.IBSnormboundsPanel);
            app.RangeMinLabel.Text = 'RangeMin';
            app.rangeMinEditField_cutoff = uieditfield(app.IBSnormboundsPanel, 'numeric');
            app.rangeMinEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @rangeMinEditField_cutoffValueChanged, true);
            app.rangeMinEditField_cutoff.Value = -1000;
            app.RangeMaxLabel_2 = uilabel(app.IBSnormboundsPanel);
            app.RangeMaxLabel_2.Text = 'ElMax';
            app.elMaxEditField_cutoff = uieditfield(app.IBSnormboundsPanel, 'numeric');
            app.elMaxEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @elMaxEditField_cutoffValueChanged, true);
            app.elMaxEditField_cutoff.Value = 1000;
            app.RangeMinLabel_2 = uilabel(app.IBSnormboundsPanel);
            app.RangeMinLabel_2.Text = 'ElMin';
            app.elMinEditField_cutoff = uieditfield(app.IBSnormboundsPanel, 'numeric');
            app.elMinEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @elMinEditField_cutoffValueChanged, true);
            app.elMinEditField_cutoff.Value = -1000;
            app.RangeMaxLabel_3 = uilabel(app.IBSnormboundsPanel);
            app.RangeMaxLabel_3.Text = 'AzMax';
            app.azMaxEditField_cutoff = uieditfield(app.IBSnormboundsPanel, 'numeric');
            app.azMaxEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @azMaxEditField_cutoffValueChanged, true);
            app.azMaxEditField_cutoff.Value = 1000;
            app.RangeMinLabel_3 = uilabel(app.IBSnormboundsPanel);
            app.RangeMinLabel_3.Text = 'AzMin';
            app.azMinEditField_cutoff = uieditfield(app.IBSnormboundsPanel, 'numeric');
            app.azMinEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @azMinEditField_cutoffValueChanged, true);
            app.azMinEditField_cutoff.Value = -1000;
            app.SetIBSgeoButton = uibutton(app.IBSnormboundsPanel, 'push');
            app.SetIBSgeoButton.ButtonPushedFcn = createCallbackFcn(app, @SetIBSgeoButtonPushed, true);
            app.SetIBSgeoButton.Text = 'Set IBS geo';
            app.MultiEllipsoidTab = uitab(app.TabGroup2);
            app.MultiEllipsoidTab.Title = 'Multi-Ellipsoid';
            app.Rx_inEditFieldLabel = uilabel(app.MultiEllipsoidTab);
            app.Rx_inEditFieldLabel.Text = 'Rx_in';
            app.Rx_inEditField = uieditfield(app.MultiEllipsoidTab, 'numeric');
            app.Rx_inEditField.ValueChangedFcn = createCallbackFcn(app, @Rx_inEditFieldValueChanged, true);
            app.Ry_inEditFieldLabel = uilabel(app.MultiEllipsoidTab);
            app.Ry_inEditFieldLabel.Text = 'Ry_in';
            app.Ry_inEditField = uieditfield(app.MultiEllipsoidTab, 'numeric');
            app.Ry_inEditField.ValueChangedFcn = createCallbackFcn(app, @Ry_inEditFieldValueChanged, true);
            app.Rz_inEditFieldLabel = uilabel(app.MultiEllipsoidTab);
            app.Rz_inEditFieldLabel.Text = 'Rz_in';
            app.Rz_inEditField = uieditfield(app.MultiEllipsoidTab, 'numeric');
            app.Rz_inEditField.ValueChangedFcn = createCallbackFcn(app, @Rz_inEditFieldValueChanged, true);
            app.InnerRadiiLabel = uilabel(app.MultiEllipsoidTab);
            app.InnerRadiiLabel.Text = 'Inner Radii';
            app.ShrinkVolumetoROIButton = uibutton(app.MultiEllipsoidTab, 'push');
            app.ShrinkVolumetoROIButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkVolumetoROIButtonPushed, true);
            app.ShrinkVolumetoROIButton.Text = 'Shrink Volume to ROI';
            app.ROIModeDropDownLabel = uilabel(app.MultiEllipsoidTab);
            app.ROIModeDropDownLabel.Text = 'ROI Mode';
            app.ROIModeDropDown = uidropdown(app.MultiEllipsoidTab);
            app.ROIModeDropDown.Items = {'Single Ellipsoid', 'Concentric Ellipsoids', 'Ellipsoid with regions', 'Concentric Ellipsoid + regions'};
            app.ROIModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIModeDropDownValueChanged, true);
            app.ROIModeDropDown.Value = 'Single Ellipsoid';
            app.ProbePanel = uipanel(app.SetupTab);
            app.ProbePanel.Title = 'Probe';
            app.AzButton = uibutton(app.ProbePanel, 'state');
            app.AzButton.Text = 'Az';
            app.ElButton = uibutton(app.ProbePanel, 'state');
            app.ElButton.Text = 'El';
            app.RaButton = uibutton(app.ProbePanel, 'state');
            app.RaButton.Text = 'Ra';
            app.ProbeDropDown = uidropdown(app.ProbePanel);
            app.ProbeDropDown.Items = {'4Z1c', 'Z6ms', 'Manual'};
            app.ProbeDropDown.Value = '4Z1c';
            app.ProbechoiceLabel = uilabel(app.ProbePanel);
            app.ProbechoiceLabel.Text = 'Probe choice';
            app.ManuallyflipdimsLabel = uilabel(app.ProbePanel);
            app.ManuallyflipdimsLabel.Text = 'Manually flip dims';
            app.LogDisplay = mlog.view.LogDisplay(app.SetupTab);
            app.LogDisplay.LogName = "EchoDecorrGUI";
            app.LogDisplay.LowerDisplayThreshold = 'MESSAGE';
            app.LoglevelDropDownLabel = uilabel(app.SetupTab);
            app.LoglevelDropDownLabel.Text = 'Log level';
            app.LoglevelDropDown = uidropdown(app.SetupTab);
            app.LoglevelDropDown.Items = {'TRACE', 'DETAIL', 'DEBUG', 'MESSAGE', 'INFO', 'WARNING', 'ERROR', 'CRITICAL', 'FATAL'};
            app.LoglevelDropDown.ValueChangedFcn = createCallbackFcn(app, @LoglevelDropDownValueChanged, true);
            app.LoglevelDropDown.Value = 'INFO';
            app.SetupAxesPanel = uipanel(app.SetupTab);
            app.SetupAxesPanel.Title = 'Setup Axes';
            app.azel_init_axes = uiaxes(app.SetupAxesPanel);
            title(app.azel_init_axes, 'Azimuth-Elevation Plane')
            app.rael_init_axes = uiaxes(app.SetupAxesPanel);
            title(app.rael_init_axes, 'Range-Elevation Plane')
            app.raaz_init_axes = uiaxes(app.SetupAxesPanel);
            title(app.raaz_init_axes, 'Range-Azimuth Plane')
            app.RangeSliderLabel = uilabel(app.SetupAxesPanel);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.SetupAxesPanel);
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.AzimuthLabel_2 = uilabel(app.SetupAxesPanel);
            app.AzimuthLabel_2.Text = 'Azimuth';
            app.AzimuthSlider = uislider(app.SetupAxesPanel);
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @AzimuthSliderValueChanged, true);
            app.ElevationLabel_2 = uilabel(app.SetupAxesPanel);
            app.ElevationLabel_2.Text = 'Elevation';
            app.ElevationSlider = uislider(app.SetupAxesPanel);
            app.ElevationSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderValueChanged, true);
            app.AcquireButton = uibutton(app.SetupAxesPanel, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed2, true);
            app.AcquireButton.Text = 'Acquire';
            app.AcquireInitVolumeLabel = uilabel(app.SetupAxesPanel);
            app.AcquireInitVolumeLabel.Text = 'Acquire Init Volume';
            app.DynamicRangeSliderLabel = uilabel(app.SetupAxesPanel);
            app.DynamicRangeSliderLabel.Text = 'Dynamic Range';
            app.DynamicRangeSlider = uislider(app.SetupAxesPanel);
            app.DynamicRangeSlider.Limits = [1 100];
            app.DynamicRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeSliderValueChanged, true);
            app.DynamicRangeSlider.Value = 60;
            app.ClearlogButton = uibutton(app.SetupTab, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.decorr_imaging_axes = uiaxes(app.ImagingTab);
            title(app.decorr_imaging_axes, 'Decorrelation Over TIme')
            xlabel(app.decorr_imaging_axes, 'X')
            ylabel(app.decorr_imaging_axes, 'Y')
            app.azel_imaging_axes = uiaxes(app.ImagingTab);
            title(app.azel_imaging_axes, 'Azimuth-Elevation Plane')
            app.rael_imaging_axes = uiaxes(app.ImagingTab);
            title(app.rael_imaging_axes, 'Range-Elevation Plane')
            app.raaz_imaging_axes = uiaxes(app.ImagingTab);
            title(app.raaz_imaging_axes, 'Range-Azimuth Plane')
            app.rfout_axes = uiaxes(app.ImagingTab);
            title(app.rfout_axes, 'RF Generator Data Output')
            xlabel(app.rfout_axes, 'X')
            ylabel(app.rfout_axes, 'Y')
            app.ImagingcontrolsPanel = uipanel(app.ImagingTab);
            app.ImagingcontrolsPanel.Title = 'Imaging controls';
            app.TrialtypeButtonGroup = uibuttongroup(app.ImagingcontrolsPanel);
            app.TrialtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrialtypeButtonGroupSelectionChanged, true);
            app.TrialtypeButtonGroup.Title = 'Trial type';
            app.MonitoringModeButton = uiradiobutton(app.TrialtypeButtonGroup);
            app.MonitoringModeButton.Text = 'Monitoring Mode';
            app.MonitoringModeButton.Value = true;
            app.ControlModeButton = uiradiobutton(app.TrialtypeButtonGroup);
            app.ControlModeButton.Text = 'Control Mode';
            app.DecorrelationSettingsPanel = uipanel(app.ImagingcontrolsPanel);
            app.DecorrelationSettingsPanel.Title = 'Decorrelation Settings';
            app.GlobalCheckBox = uicheckbox(app.DecorrelationSettingsPanel);
            app.GlobalCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlobalCheckBoxValueChanged, true);
            app.GlobalCheckBox.Text = 'Global';
            app.GlobalCheckBox.Value = true;
            app.LocalCheckBox = uicheckbox(app.DecorrelationSettingsPanel);
            app.LocalCheckBox.ValueChangedFcn = createCallbackFcn(app, @LocalCheckBoxValueChanged, true);
            app.LocalCheckBox.Text = 'Local';
            app.LocalCheckBox.Value = true;
            app.MotioncorrectCheckBox = uicheckbox(app.DecorrelationSettingsPanel);
            app.MotioncorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotioncorrectCheckBoxValueChanged, true);
            app.MotioncorrectCheckBox.Text = 'Motion correct';
            app.NumshamEditFieldLabel = uilabel(app.DecorrelationSettingsPanel);
            app.NumshamEditFieldLabel.Text = 'Num sham';
            app.NumshamEditField = uieditfield(app.DecorrelationSettingsPanel, 'numeric');
            app.ControlThreshEditFieldLabel = uilabel(app.DecorrelationSettingsPanel);
            app.ControlThreshEditFieldLabel.Text = 'Control Thresh';
            app.ControlThreshEditField = uieditfield(app.DecorrelationSettingsPanel, 'numeric');
            app.ControlThreshEditField.ValueChangedFcn = createCallbackFcn(app, @ControlThreshEditFieldValueChanged, true);
            app.RangeSlider_2Label = uilabel(app.ImagingTab);
            app.RangeSlider_2Label.Text = 'Range';
            app.RangeSlider_imaging = uislider(app.ImagingTab);
            app.RangeSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @RangeSlider_imagingValueChanged, true);
            app.ElevationLabel_3 = uilabel(app.ImagingTab);
            app.ElevationLabel_3.Text = 'Elevation';
            app.ElevationSlider_imaging = uislider(app.ImagingTab);
            app.ElevationSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @ElevationSlider_imagingValueChanged, true);
            app.AzimuthLabel_3 = uilabel(app.ImagingTab);
            app.AzimuthLabel_3.Text = 'Azimuth';
            app.AzimuthSlider_imaging = uislider(app.ImagingTab);
            app.AzimuthSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @AzimuthSlider_imagingValueChanged, true);
            app.AzimuthSlider_imaging.ValueChangingFcn = createCallbackFcn(app, @AzimuthSlider_imagingValueChanging, true);
            app.BmodeDynamicRangeLabel = uilabel(app.ImagingTab);
            app.BmodeDynamicRangeLabel.Text = 'Bmode Dyn. Range';
            app.DynamicRangeSlider_imaging = uislider(app.ImagingTab);
            app.DynamicRangeSlider_imaging.Limits = [1 200];
            app.DynamicRangeSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeSlider_imagingValueChanged, true);
            app.DynamicRangeSlider_imaging.Value = 60;
            app.ImagingPanel = uipanel(app.ImagingTab);
            app.ImagingPanel.Title = 'Imaging';
            app.ReadyLamp_2Label = uilabel(app.ImagingPanel);
            app.ReadyLamp_2Label.Text = 'Ready?';
            app.ReadyLamp_2 = uilamp(app.ImagingPanel);
            app.StartAutomaticRecordingButton = uibutton(app.ImagingPanel, 'push');
            app.StartAutomaticRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutomaticRecordingButtonPushed, true);
            app.StartAutomaticRecordingButton.Text = 'Start Automatic Recording';
            app.PauseButton = uibutton(app.ImagingPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.ImagingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RecordSingleVolumeButton = uibutton(app.ImagingPanel, 'push');
            app.RecordSingleVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @RecordSingleVolumeButtonPushed, true);
            app.RecordSingleVolumeButton.Text = 'Record Single Volume';
            app.StartablationautomaticallyCheckBox = uicheckbox(app.ImagingPanel);
            app.StartablationautomaticallyCheckBox.Text = 'Start ablation automatically';
            app.PreablationvolsEditFieldLabel = uilabel(app.ImagingPanel);
            app.PreablationvolsEditFieldLabel.Text = 'Pre-ablation vols';
            app.PreablationvolsEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.PreablationvolsEditField.ValueChangedFcn = createCallbackFcn(app, @PreablationvolsEditFieldValueChanged, true);
            app.DynamicRangeSlider_2Label_2 = uilabel(app.ImagingTab);
            app.DynamicRangeSlider_2Label_2.Text = 'Decorr Dyn. Range';
            app.DecorrDynamicRangeSlider_imaging = uislider(app.ImagingTab);
            app.DecorrDynamicRangeSlider_imaging.Limits = [1 100];
            app.DecorrDynamicRangeSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @DecorrDynamicRangeSlider_imagingValueChanged, true);
            app.DecorrDynamicRangeSlider_imaging.Value = 60;
            app.MindecdisplayEditFieldLabel = uilabel(app.ImagingTab);
            app.MindecdisplayEditFieldLabel.Text = 'Min dec. display';
            app.MindecdisplayEditField = uieditfield(app.ImagingTab, 'numeric');
            app.MindecdisplayEditField.Value = -4;
            app.OverridePanel = uipanel(app.ImagingTab);
            app.OverridePanel.Title = 'Override';
            app.ActivateRFgenButton = uibutton(app.OverridePanel, 'push');
            app.ActivateRFgenButton.Text = 'Activate RF gen.';
            app.StopimagingButton = uibutton(app.OverridePanel, 'push');
            app.StopimagingButton.ButtonPushedFcn = createCallbackFcn(app, @StopimagingButtonPushed, true);
            app.StopimagingButton.Text = 'Stop imaging';
            app.SaveRFButton = uibutton(app.ImagingTab, 'push');
            app.SaveRFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRFButtonPushed, true);
            app.SaveRFButton.Text = 'Save RF';
            app.EndTrialButton = uibutton(app.ImagingTab, 'push');
            app.EndTrialButton.ButtonPushedFcn = createCallbackFcn(app, @EndTrialButtonPushed, true);
            app.EndTrialButton.Text = 'End Trial';
            app.ExperimentInfoPanel = uipanel(app.ImagingTab);
            app.ExperimentInfoPanel.Title = 'Experiment Info';
            app.logArtifDecorrEditFieldLabel = uilabel(app.ExperimentInfoPanel);
            app.logArtifDecorrEditFieldLabel.Text = 'log Artif. Decorr:';
            app.logArtifDecorrEditField = uieditfield(app.ExperimentInfoPanel, 'numeric');
            app.logArtifDecorrEditField.Editable = 'off';
            app.logCumDecEditFieldLabel = uilabel(app.ExperimentInfoPanel);
            app.logCumDecEditFieldLabel.Text = ' log Cum. Dec:';
            app.logCumDecEditField = uieditfield(app.ExperimentInfoPanel, 'numeric');
            app.logCumDecEditField.Editable = 'off';
            app.ElapsedTimeEditFieldLabel = uilabel(app.ExperimentInfoPanel);
            app.ElapsedTimeEditFieldLabel.Text = 'Elapsed Time';
            app.ElapsedTimeEditField = uieditfield(app.ExperimentInfoPanel, 'numeric');
            app.ElapsedTimeEditField.Editable = 'off';
            app.ShamvolumesEditFieldLabel = uilabel(app.ExperimentInfoPanel);
            app.ShamvolumesEditFieldLabel.Text = 'Sham volumes';
            app.ShamvolumesEditField = uieditfield(app.ExperimentInfoPanel, 'numeric');
            app.ShamvolumesEditField.Editable = 'off';
            app.AblationvolumesEditFieldLabel = uilabel(app.ExperimentInfoPanel);
            app.AblationvolumesEditFieldLabel.Text = 'Ablation volumes';
            app.AblationvolumesEditField = uieditfield(app.ExperimentInfoPanel, 'numeric');
            app.AblationvolumesEditField.Editable = 'off';
            app.VolcompletiontimeEditFieldLabel = uilabel(app.ExperimentInfoPanel);
            app.VolcompletiontimeEditFieldLabel.Text = 'Vol completion time';
            app.VolcompletiontimeEditField = uieditfield(app.ExperimentInfoPanel, 'numeric');
            app.VolcompletiontimeEditField.Editable = 'off';
            app.LogDisplay2 = mlog.view.LogDisplay(app.ImagingTab);
            app.LogDisplay2.LogName = "EchoDecorrGUI";
            app.LogDisplay2.LowerDisplayThreshold = 'DETAIL';
            app.LoglevelDropDown_2Label = uilabel(app.ImagingTab);
            app.LoglevelDropDown_2Label.Text = 'Log level';
            app.LoglevelDropDown_imaging = uidropdown(app.ImagingTab);
            app.LoglevelDropDown_imaging.Items = {'TRACE', 'DETAIL', 'DEBUG', 'MESSAGE', 'INFO', 'WARNING', 'CRITICAL', 'FATAL'};
            app.LoglevelDropDown_imaging.ValueChangedFcn = createCallbackFcn(app, @LoglevelDropDown_imagingValueChanged, true);
            app.LoglevelDropDown_imaging.Value = 'INFO';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.azel_review_axes = uiaxes(app.ReviewTab);
            title(app.azel_review_axes, 'Azimuth-Elevation Plane')
            app.raaz_review_axes = uiaxes(app.ReviewTab);
            title(app.raaz_review_axes, 'Range-Elevation Plane')
            xlabel(app.raaz_review_axes, 'X')
            ylabel(app.raaz_review_axes, 'Y')
            app.rael_review_axes = uiaxes(app.ReviewTab);
            title(app.rael_review_axes, 'Range-Azimiuth Plane')
            xlabel(app.rael_review_axes, 'X')
            ylabel(app.rael_review_axes, 'Y')
            app.review_decorr_axes = uiaxes(app.ReviewTab);
            title(app.review_decorr_axes, 'Decorr Over Time')
            xlabel(app.review_decorr_axes, 'X')
            ylabel(app.review_decorr_axes, 'Y')
            app.ReviewControlPanel = uipanel(app.ReviewTab);
            app.ReviewControlPanel.Title = 'Review Control';
            app.LoadExperimentButton = uibutton(app.ReviewControlPanel, 'push');
            app.LoadExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentButtonPushed, true);
            app.LoadExperimentButton.Text = 'Load Experiment';
            app.Label = uilabel(app.ReviewControlPanel);
            app.DirectoryLabel_2 = uilabel(app.ReviewControlPanel);
            app.DirectoryLabel_2.Text = 'Directory:';
            app.LoadExperimentfromfileButton = uibutton(app.ReviewControlPanel, 'push');
            app.LoadExperimentfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentfromfileButtonPushed, true);
            app.LoadExperimentfromfileButton.Text = 'Load Experiment from file';
            app.ExportButton = uibutton(app.ReviewControlPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SigmaEditFieldLabel = uilabel(app.ReviewControlPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.ReviewControlPanel, 'numeric');
            app.ThreshEditFieldLabel = uilabel(app.ReviewControlPanel);
            app.ThreshEditFieldLabel.Text = 'Thresh';
            app.ThreshEditField = uieditfield(app.ReviewControlPanel, 'numeric');
            app.SigmaEditField_2Label = uilabel(app.ReviewControlPanel);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaEditField_2 = uieditfield(app.ReviewControlPanel, 'text');
            app.NumberofVolumesEditFieldLabel = uilabel(app.ReviewControlPanel);
            app.NumberofVolumesEditFieldLabel.Text = 'Number of Volumes';
            app.NumberofVolumesEditField = uieditfield(app.ReviewControlPanel, 'text');
            app.DecorrThreshEditField_2Label = uilabel(app.ReviewControlPanel);
            app.DecorrThreshEditField_2Label.Text = 'Decorr Thresh';
            app.DecorrThreshEditField_2 = uieditfield(app.ReviewControlPanel, 'text');
            app.FrameRateEditField_2Label = uilabel(app.ReviewControlPanel);
            app.FrameRateEditField_2Label.Text = 'Frame Rate';
            app.FrameRateEditField_2 = uieditfield(app.ReviewControlPanel, 'numeric');
            app.Panel2 = uipanel(app.ReviewTab);
            app.Panel2.Title = 'Panel2';
            app.beeginbutton = uibutton(app.Panel2, 'push');
            app.beeginbutton.Text = '<<';
            app.endbutton = uibutton(app.Panel2, 'push');
            app.endbutton.Text = '>>';
            app.back1button = uibutton(app.Panel2, 'push');
            app.back1button.Text = '<';
            app.forward1button = uibutton(app.Panel2, 'push');
            app.forward1button.Text = '>';
            app.SelectDataDropDownLabel = uilabel(app.Panel2);
            app.SelectDataDropDownLabel.Text = 'Select Data';
            app.SelectDataDropDown = uidropdown(app.Panel2);
            app.SelectDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDataDropDownValueChanged, true);
            app.Panel2_2 = uipanel(app.ReviewTab);
            app.Panel2_2.Title = 'Panel2';
            app.RangeSlider_2Label_2 = uilabel(app.ReviewTab);
            app.RangeSlider_2Label_2.Text = 'Range';
            app.RangeSlider_review = uislider(app.ReviewTab);
            app.RangeSlider_review.ValueChangedFcn = createCallbackFcn(app, @RangeSlider_reviewValueChanged, true);
            app.ElevationLabel = uilabel(app.ReviewTab);
            app.ElevationLabel.Text = 'Elevation';
            app.ElevationSlider_review = uislider(app.ReviewTab);
            app.AzimuthLabel = uilabel(app.ReviewTab);
            app.AzimuthLabel.Text = 'Azimuth';
            app.AzimuthSlider_review = uislider(app.ReviewTab);
            app.AzimuthSlider_review.ValueChangedFcn = createCallbackFcn(app, @AzimuthSlider_reviewValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.LevelMenu = uimenu(app.ContextMenu);
            app.LevelMenu.Text = 'Level';
            app.DefaultMenu = uimenu(app.LevelMenu);
            app.DefaultMenu.Text = 'Default';
            app.DebugMenu = uimenu(app.LevelMenu);
            app.DebugMenu.Text = 'Debug';
            app.WarningMenu = uimenu(app.LevelMenu);
            app.WarningMenu.Text = 'Warning';
            app.ErrorMenu = uimenu(app.LevelMenu);
            app.ErrorMenu.Text = 'Error';
            app.CriticalMenu = uimenu(app.LevelMenu);
            app.CriticalMenu.Text = 'Critical';
            app.ClearMenu = uimenu(app.ContextMenu);
            app.ClearMenu.Text = 'Clear';
            % Assign app.ContextMenu
            app.LogDisplay.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DecorrApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end