classdef decorr2d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        InitPanel                      matlab.ui.container.Panel
        LoadDataButton                 matlab.ui.control.Button
        DynRangeSlider                 matlab.ui.control.Slider
        DynRangeSliderLabel            matlab.ui.control.Label
        ROIPanel                       matlab.ui.container.Panel
        SetROIButton                   matlab.ui.control.Button
        ElevationCenterEditField       matlab.ui.control.NumericEditField
        ElevationCenterEditFieldLabel  matlab.ui.control.Label
        AzimuthCenterEditField         matlab.ui.control.NumericEditField
        AzimuthCenterEditFieldLabel    matlab.ui.control.Label
        DepthCenterEditField           matlab.ui.control.NumericEditField
        DepthCenterEditFieldLabel      matlab.ui.control.Label
        ROIRadiusEditField             matlab.ui.control.NumericEditField
        ROIRadiusEditFieldLabel        matlab.ui.control.Label
        SerialPanel                    matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        DTab                           matlab.ui.container.Tab
        deaz_init_2d_axis              matlab.ui.control.UIAxes
        biplaneTab                     matlab.ui.container.Tab
        deaz_init_biplane_axis         matlab.ui.control.UIAxes
        deel_init_biplane_axis         matlab.ui.control.UIAxes
        SettingsPanel                  matlab.ui.container.Panel
        ImagingPanel                   matlab.ui.container.Panel
        SigmaEditField_2               matlab.ui.control.NumericEditField
        SigmaEditField_2Label          matlab.ui.control.Label
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        FrameRateEditField             matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel        matlab.ui.control.Label
        ScaleFactorEditField           matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel      matlab.ui.control.Label
        ElevationAngleEditField        matlab.ui.control.NumericEditField
        ElevationAngleEditFieldLabel   matlab.ui.control.Label
        AzimuthAngleEditField          matlab.ui.control.NumericEditField
        AzimuthAngleEditFieldLabel     matlab.ui.control.Label
        DepthEditField                 matlab.ui.control.NumericEditField
        DepthEditFieldLabel            matlab.ui.control.Label
        SetParametersButton            matlab.ui.control.Button
        DataFolderPanel                matlab.ui.container.Panel
        SelectFolderButton             matlab.ui.control.Button
        ModeButtonGroup                matlab.ui.container.ButtonGroup
        biplaneButton                  matlab.ui.control.RadioButton
        DButton                        matlab.ui.control.RadioButton
        ImagingTab                     matlab.ui.container.Tab
        ImagingPanel_2                 matlab.ui.container.Panel
        NoiseCompensationCheckBox      matlab.ui.control.CheckBox
        RecordButton                   matlab.ui.control.StateButton
        CurrentVolumeEditField         matlab.ui.control.NumericEditField
        CurrentVolumeEditFieldLabel    matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        TabGroup2_2                    matlab.ui.container.TabGroup
        DTab_2                         matlab.ui.container.Tab
        deaz_imaging_2d_axis           matlab.ui.control.UIAxes
        biplaneTab_2                   matlab.ui.container.Tab
        deel_imaging_biplane_axis      matlab.ui.control.UIAxes
        deaz_imaging_biplane_axis      matlab.ui.control.UIAxes
        UIAxes6_2                      matlab.ui.control.UIAxes
        decorrSeries_axis              matlab.ui.control.UIAxes
        ReviewTab                      matlab.ui.container.Tab
        UIAxes2_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Experiment;
        type;
        recordingActive;
    end
    
    methods (Access = private)
        
        function setExperimentObject(app)
            if app.DButton.Value
                app.type = '2D';
            else
                app.type = 'biplane';
            end
            app.Experiment = ExperimentClass2D(app.type);
        end
        
        function displayInit2D(app)
            displayData = app.Experiment.initDataSet.rawData_cart(:,:,1);
            imagesc(app.deaz_init_2d_axis,log10(abs(displayData)));
            colormap(app.deaz_init_2d_axis,'gray')
        end
        function displayInitBiplane(app)
            displayData1 = app.Experiment.initDataSet.rawData_cart{1};
            displayData1 = displayData1(:,:,1);
            displayData2 = app.Experiment.initDataSet.rawData_cart{2};
            displayData2 = displayData2(:,:,1);
            imagesc(app.deaz_init_biplane_axis,log10(abs(displayData1)));
            colormap(app.deaz_init_biplane_axis,'gray')
            imagesc(app.deel_init_biplane_axis,log10(abs(displayData2)));
            colormap(app.deel_init_biplane_axis,'gray')
        end
        function displayImaging2D(app)
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{end}.rawData_cart(:,:,1)));
            
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr);
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr);
            end
            minDecorrVal = -4;
            maxDecorrVal = 0;
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal) + 1; 
            decorrScaled(decorrScaled>1) = 1; 
            decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            dynamicRange = 100;
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;

            %green = hybrid_RGB(:,:,2);
            %green(overLayImAzel) = 1; 
            %hybrid_RGB(:,:,2) = green; 
            image(app.deaz_imaging_2d_axis,hybrid_RGB);
        end
        function displayImagingBiplane(app)
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{end}.rawData_cart{1}));
            BmodeIn = BmodeIn(:,:,1);
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr{1});
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr{1});
            end
            minDecorrVal = -4;
            maxDecorrVal = 0;
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal) + 1; 
            decorrScaled(decorrScaled>1) = 1; 
            decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            dynamicRange = 100;
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            image(app.deaz_imaging_biplane_axis,hybrid_RGB);
            
            BmodeIn = 20*log10(abs(app.Experiment.dataSeries{end}.rawData_cart{2}));
            BmodeIn = BmodeIn(:,:,1);
            bMode_scale = 20*log10(.25);
            if app.NoiseCompensationCheckBox.Value
                decorrIn = log10(app.Experiment.adjCumulativeDecorr{2});
            else
                decorrIn = log10(app.Experiment.cumulativeDecorr{2});
            end
            minDecorrVal = -5;
            maxDecorrVal = 0;
            decorrScaled = decorrIn/(maxDecorrVal-minDecorrVal) + 1; 
            decorrScaled(decorrScaled>1) = 1; 
            decorrScaled(decorrScaled<0) = 0; 
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            dynamicRange = 100;
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            image(app.deel_imaging_biplane_axis,hybrid_RGB);
            drawnow; 
        end
        function displayImaging(app)
            switch app.Experiment.mode
                case '2D'
                    app.displayImaging2D();
                case 'biplane'
                    app.displayImagingBiplane(); 
            end
        end
        function displayInit(app)
            switch app.Experiment.mode
                case '2D'
                    app.displayInit2D();
                case 'biplane'
                    app.displayInitBiplane(); 
            end
        end
        function displayDecorrSeries(app)
            switch app.Experiment.mode
                case '2D'
                    app.displayDecorrSeries2D();
                case 'biplane'
                    app.displayDecorrSeriesBiplane();
            end
        end
        function displayDecorrSeriesBiplane(app)
            plot(app.decorrSeries_axis, log10(app.Experiment.meanDecorrSeries{1}))
            hold(app.decorrSeries_axis, 'on')
            plot(app.decorrSeries_axis, log10(app.Experiment.meanDecorrSeries{2}))
        end
        function displayDecorrSeries2D(app)
            plot(app.decorrSeries_axis, log10(app.Experiment.meanDecorrSeries))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.setExperimentObject()
            app.Experiment.initDataFolderGUI()
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            azAngle = app.AzimuthAngleEditField.Value;
            elAngle = app.ElevationAngleEditField.Value;
            depth = app.DepthEditField.Value;
            frameRate = app.FrameRateEditField.Value;
            scaleFactor = app.ScaleEditField.Value;
            sigma = app.SigmaEditField_2.Value;
            app.Experiment.setImagingParams(azAngle, elAngle, depth, scaleFactor, frameRate,sigma)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.Experiment.getInitDataSet()
            app.displayInit();
            
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            while app.RecordButton.Value
                if app.Experiment.checkNextDataSetReady
                    app.Experiment.getNextDataSet;
                    app.Experiment.processLatestDataSet;
                    app.displayImaging();
                    app.displayDecorrSeries()
                end
                pause(.1)
            end
        end

        % Button pushed function: SetROIButton
        function SetROIButtonPushed(app, event)
            z0 = app.DepthCenterEditField.Value;
            y0 = app.AzimuthCenterEditField.Value;
            x0 = app.ElevationCenterEditField.Value;
            r = app.ROIRadiusEditField.Value;
            app.Experiment.setROIParams(z0,y0,x0,r)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SettingsPanel = uipanel(app.SetupTab);
            app.SettingsPanel.Title = 'Settings';
            app.ModeButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ModeButtonGroup.Title = 'Mode';
            app.DButton = uiradiobutton(app.ModeButtonGroup);
            app.DButton.Text = '2D';
            app.DButton.Value = true;
            app.biplaneButton = uiradiobutton(app.ModeButtonGroup);
            app.biplaneButton.Text = 'biplane';
            app.DataFolderPanel = uipanel(app.SettingsPanel);
            app.DataFolderPanel.Title = 'Data Folder';
            app.SelectFolderButton = uibutton(app.DataFolderPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ImagingPanel = uipanel(app.SettingsPanel);
            app.ImagingPanel.Title = 'Imaging';
            app.SetParametersButton = uibutton(app.ImagingPanel, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.DepthEditFieldLabel = uilabel(app.ImagingPanel);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.AzimuthAngleEditFieldLabel = uilabel(app.ImagingPanel);
            app.AzimuthAngleEditFieldLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.ElevationAngleEditFieldLabel = uilabel(app.ImagingPanel);
            app.ElevationAngleEditFieldLabel.Text = 'Elevation Angle';
            app.ElevationAngleEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.ScaleFactorEditFieldLabel = uilabel(app.ImagingPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.FrameRateEditFieldLabel = uilabel(app.ImagingPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.ScaleEditFieldLabel = uilabel(app.ImagingPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.ImagingPanel, 'numeric');
            app.SigmaEditField_2Label = uilabel(app.ImagingPanel);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaEditField_2 = uieditfield(app.ImagingPanel, 'numeric');
            app.TabGroup2 = uitabgroup(app.SetupTab);
            app.DTab = uitab(app.TabGroup2);
            app.DTab.Title = '2D';
            app.deaz_init_2d_axis = uiaxes(app.DTab);
            title(app.deaz_init_2d_axis, 'Depth Azimuth')
            xlabel(app.deaz_init_2d_axis, 'X')
            ylabel(app.deaz_init_2d_axis, 'Y')
            zlabel(app.deaz_init_2d_axis, 'Z')
            app.biplaneTab = uitab(app.TabGroup2);
            app.biplaneTab.Title = 'biplane';
            app.deel_init_biplane_axis = uiaxes(app.biplaneTab);
            title(app.deel_init_biplane_axis, 'Depth Azimuth')
            xlabel(app.deel_init_biplane_axis, 'X')
            ylabel(app.deel_init_biplane_axis, 'Y')
            zlabel(app.deel_init_biplane_axis, 'Z')
            app.deaz_init_biplane_axis = uiaxes(app.biplaneTab);
            title(app.deaz_init_biplane_axis, 'Depth Elevation')
            xlabel(app.deaz_init_biplane_axis, 'X')
            ylabel(app.deaz_init_biplane_axis, 'Y')
            zlabel(app.deaz_init_biplane_axis, 'Z')
            app.SerialPanel = uipanel(app.SetupTab);
            app.SerialPanel.Title = 'Serial';
            app.ROIPanel = uipanel(app.SetupTab);
            app.ROIPanel.Title = 'ROI';
            app.ROIRadiusEditFieldLabel = uilabel(app.ROIPanel);
            app.ROIRadiusEditFieldLabel.Text = 'ROI Radius';
            app.ROIRadiusEditField = uieditfield(app.ROIPanel, 'numeric');
            app.DepthCenterEditFieldLabel = uilabel(app.ROIPanel);
            app.DepthCenterEditFieldLabel.Text = 'Depth Center';
            app.DepthCenterEditField = uieditfield(app.ROIPanel, 'numeric');
            app.AzimuthCenterEditFieldLabel = uilabel(app.ROIPanel);
            app.AzimuthCenterEditFieldLabel.Text = 'Azimuth Center';
            app.AzimuthCenterEditField = uieditfield(app.ROIPanel, 'numeric');
            app.ElevationCenterEditFieldLabel = uilabel(app.ROIPanel);
            app.ElevationCenterEditFieldLabel.Text = 'Elevation Center';
            app.ElevationCenterEditField = uieditfield(app.ROIPanel, 'numeric');
            app.SetROIButton = uibutton(app.ROIPanel, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.InitPanel = uipanel(app.SetupTab);
            app.InitPanel.Title = 'Init';
            app.DynRangeSliderLabel = uilabel(app.InitPanel);
            app.DynRangeSliderLabel.Text = 'Dyn. Range';
            app.DynRangeSlider = uislider(app.InitPanel);
            app.LoadDataButton = uibutton(app.InitPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.decorrSeries_axis = uiaxes(app.ImagingTab);
            title(app.decorrSeries_axis, 'Decorrelation')
            xlabel(app.decorrSeries_axis, 'X')
            ylabel(app.decorrSeries_axis, 'Y')
            zlabel(app.decorrSeries_axis, 'Z')
            app.UIAxes6_2 = uiaxes(app.ImagingTab);
            title(app.UIAxes6_2, 'Serial')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.TabGroup2_2 = uitabgroup(app.ImagingTab);
            app.DTab_2 = uitab(app.TabGroup2_2);
            app.DTab_2.Title = '2D';
            app.deaz_imaging_2d_axis = uiaxes(app.DTab_2);
            title(app.deaz_imaging_2d_axis, 'Depth Azimuth')
            xlabel(app.deaz_imaging_2d_axis, 'X')
            ylabel(app.deaz_imaging_2d_axis, 'Y')
            zlabel(app.deaz_imaging_2d_axis, 'Z')
            app.biplaneTab_2 = uitab(app.TabGroup2_2);
            app.biplaneTab_2.Title = 'biplane';
            app.deaz_imaging_biplane_axis = uiaxes(app.biplaneTab_2);
            title(app.deaz_imaging_biplane_axis, 'Depth Azimuth')
            xlabel(app.deaz_imaging_biplane_axis, 'X')
            ylabel(app.deaz_imaging_biplane_axis, 'Y')
            zlabel(app.deaz_imaging_biplane_axis, 'Z')
            app.deel_imaging_biplane_axis = uiaxes(app.biplaneTab_2);
            title(app.deel_imaging_biplane_axis, 'Depth Elevation')
            xlabel(app.deel_imaging_biplane_axis, 'X')
            ylabel(app.deel_imaging_biplane_axis, 'Y')
            zlabel(app.deel_imaging_biplane_axis, 'Z')
            app.ImagingPanel_2 = uipanel(app.ImagingTab);
            app.ImagingPanel_2.Title = 'Imaging';
            app.PauseButton = uibutton(app.ImagingPanel_2, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.ImagingPanel_2, 'push');
            app.StopButton.Text = 'Stop';
            app.CurrentVolumeEditFieldLabel = uilabel(app.ImagingPanel_2);
            app.CurrentVolumeEditFieldLabel.Text = 'Current Volume';
            app.CurrentVolumeEditField = uieditfield(app.ImagingPanel_2, 'numeric');
            app.RecordButton = uibutton(app.ImagingPanel_2, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.NoiseCompensationCheckBox = uicheckbox(app.ImagingPanel_2);
            app.NoiseCompensationCheckBox.Text = 'Noise Compensation';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = decorr2d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end